classdef pokerGameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReadyButton         matlab.ui.control.StateButton
        ShotButton          matlab.ui.control.Button
        PassButton          matlab.ui.control.Button
        CardNum_player_1    matlab.ui.control.Label
        backCard_player_1   matlab.ui.control.Image
        CardNum_player_2    matlab.ui.control.Label
        backCard_player_2   matlab.ui.control.Image
        avatar_player_2     matlab.ui.control.Image
        avatar_currplayer   matlab.ui.control.Image
        avatar_player_1     matlab.ui.control.Image
        CardNum_currplayer  matlab.ui.control.Label
        Image               matlab.ui.control.Image
        Image2              matlab.ui.control.Image
    end

    
    properties (Access = public)
        % Three players
         currPlayer 
         player_1
         player_2
         gameEngine
    end
    
    methods (Access = private)
        
        function selectCard(app, src, event, indx)
            if (app.currPlayer.cards_img{indx}.Position(1,2) == 22)
                app.currPlayer.cards_img{indx}.Position(1,2) = 62;
            elseif (app.currPlayer.cards_img{indx}.Position(1,2) == 62)
                app.currPlayer.cards_img{indx}.Position(1,2) = 22;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadyButton
        function ReadyButtonValueChanged(app, event)
            value = app.ReadyButton.Value;
            app.currPlayer.isActive = true;
            app.ReadyButton.Visible = false;
            % update 'ready' in UIs
            app.CardNum_currplayer.Text = 'Ready';
            app.gameEngine.update;
            drawnow
            
            if (app.gameEngine.isDistribute == false)
                app.gameEngine.assignRole;
                app.gameEngine.distributeCards;
                app.gameEngine.isDistribute = true;
            end
            
            mid = (app.currPlayer.cardNum + 1)/2;
            % Create curr player cards components and set them as invisible
            for i = 1:app.currPlayer.cardNum
                app.currPlayer.cards_img{i} = uiimage(app.UIFigure,'ImageSource', app.currPlayer.cards{3, i});
                x = 565 + (i - mid) * 32;
                app.currPlayer.cards_img{i}.Position = [x, 22, 173, 256];
                app.currPlayer.cards_img{i}.ImageClickedFcn = {@app.selectCard, i};
                % app.currPlayer.cards_img{i}.Visible = false;
            end
            
            
            if (app.player_1.isActive == true && app.player_2.isActive == true)
                app.gameEngine.isStart = true;
                app.gameEngine.startGame;
            end
            
            if (app.gameEngine.isStart == true)
                app.gameEngine.update;
                % curr UI
                
                % other UIs
            end
        end

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if (value == true)
                app.Button.Visible = true;
            else
                app.Button.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ReadyButton = uibutton(app.UIFigure, 'state');
            app.ReadyButton.ValueChangedFcn = createCallbackFcn(app, @ReadyButtonValueChanged, true);
            app.ReadyButton.Text = 'Ready';
            app.ShotButton = uibutton(app.UIFigure, 'push');
            app.ShotButton.Icon = 'shot.png';
            app.ShotButton.Text = 'Shot';
            app.PassButton = uibutton(app.UIFigure, 'push');
            app.PassButton.Icon = 'pass.png';
            app.PassButton.Text = 'Pass';
            app.CardNum_player_1 = uilabel(app.UIFigure);
            app.CardNum_player_1.Text = 'Not ready!';
            app.backCard_player_1 = uiimage(app.UIFigure);
            app.backCard_player_1.ImageSource = 'poker_back.jpg';
            app.CardNum_player_2 = uilabel(app.UIFigure);
            app.CardNum_player_2.Text = 'Not ready!';
            app.backCard_player_2 = uiimage(app.UIFigure);
            app.backCard_player_2.ImageSource = 'poker_back.jpg';
            app.avatar_player_2 = uiimage(app.UIFigure);
            app.avatar_player_2.ImageSource = 'icon_default.png';
            app.avatar_currplayer = uiimage(app.UIFigure);
            app.avatar_currplayer.ImageSource = 'icon_default.png';
            app.avatar_player_1 = uiimage(app.UIFigure);
            app.avatar_player_1.ImageSource = 'icon_default.png';
            app.CardNum_currplayer = uilabel(app.UIFigure);
            app.CardNum_currplayer.Text = 'Not ready!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'clubs6.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'clubs6.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokerGameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end